classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RightPanel                 matlab.ui.container.Panel
        ButtonStopTesting          matlab.ui.control.Button
        ButtonStartTesting         matlab.ui.control.Button
        ButtonStartTraining        matlab.ui.control.Button
        ButtonSaveAdcData          matlab.ui.control.Button
        DropDownSelectBaudRate     matlab.ui.control.DropDown
        Label_7                    matlab.ui.control.Label
        ButtonClose                matlab.ui.control.Button
        LampConnectStatus          matlab.ui.control.Lamp
        Label_6                    matlab.ui.control.Label
        EditFieldSetClass          matlab.ui.control.EditField
        Label_5                    matlab.ui.control.Label
        LampRecordingStatus        matlab.ui.control.Lamp
        Label_3                    matlab.ui.control.Label
        ButtonOpenFolder           matlab.ui.control.Button
        ButtonDisconnect           matlab.ui.control.Button
        ButtonConnect              matlab.ui.control.Button
        DropDownSelectComport      matlab.ui.control.DropDown
        Label_2                    matlab.ui.control.Label
        ButtonStopRecord           matlab.ui.control.Button
        ButtonStartRecord          matlab.ui.control.Button
        LeftPanel                  matlab.ui.container.Panel
        UITableSatisticalFeatures  matlab.ui.control.Table
        UITable                    matlab.ui.control.Table
        LabelDataLength            matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        dataFolder="./data"; %記錄檔存放路徑
        checkpointFolder="./data/checkpoint"; %記錄檔檢查點路徑
        AdcData=[]; %初始化ADC陣列
        classCount=[0, 0, 0]; %紀錄每個類別的次數

        flagConnect=false; %是否連線
        flagRecording=false; %是否開始記錄
        flagTesting=false; %是否開始測試
        recordingData=[]; %暫存記錄檔
        serialObject=[];
    end
    methods (Access = private)
        
        function result = main(app)

            app.AdcData=[]; %初始化ADC陣列
            tempRead2500=zeros(1,2500); %暫存每2500筆資料，先產生0分配記憶體空間會更快。
            tempRead5000=zeros(1,5000); %暫存每5000筆資料，預覽用，先產生0分配記憶體空間會更快。
            
            %預覽用，還沒開始記錄資料
            app.Label.Text = "預覽中...";
            while true
                if app.flagRecording == true %如果按下開始紀錄就跳出迴圈
                    break;
                end
                if app.flagConnect==false %如果預覽中有按中斷連線
                    app.disconnect();
                    return;
                end
                
                tempRead5000=tempRead5000(2500:5000); %把後面2500筆資料往前移動

                for readDataCount = 2500:5000 %一次讀2500筆
                    tempRead=double(readline(app.serialObject)); %把資料讀進來並轉成double型態
                    assignin('base','tempRead', tempRead);
                    if isempty(tempRead) %如果式空陣列，代表Timeout
                        app.Label.Text = "讀不到資料...我要斷線囉~掰掰~";
                        app.disconnect();
                        return;
                    end
                    if isnan(tempRead) %如果是NAN就變0
                        tempRead=0;
                    end
                    tempRead5000(readDataCount) = tempRead; %加到後2500筆
                    app.LabelDataLength.Text = "預覽資料長度: "+(readDataCount);

                end
                

                %如果按下測試
                if app.flagTesting==true
                    result=app.getClass(tempRead5000);
                    csvwrite("class.csv", result) %將結果儲存給老師老師用~~
                    app.Label.Text = "類別: "+result;
                    
                    if result==1
                        app.classCount(1)=app.classCount(1)+1;
                    elseif result==23
                        app.classCount(2)=app.classCount(2)+1;
                    elseif result==4
                        app.classCount(3)=app.classCount(3)+1;
                    end
                    %disp(getClassProbability(app));
                    app.UITable.Data=getClassProbability(app);
                end
                plot(app.UIAxes, tempRead5000); %畫圖
            end
            
            %按下開始記錄才會跳到下面

            app.Label.Text = "開始記錄...";
            while true
                if app.flagRecording == false %如果按下停止紀錄就跳出迴圈
                    app.ButtonDisconnect.Enable="on"; 
                    app.ButtonDisconnect.Tooltip="";

                    app.Label.Text = "停止紀錄，已記錄資料量:"+string(length(app.AdcData));
                    break;
                end

                tempAdcDataLength=length(app.AdcData); %暫存ADC資料長度
                for readDataCount = 1:2500 %一次讀2500筆
                    
                    tempRead=double(readline(app.serialObject)); %把資料讀進來並轉成double型態
                    
                    if isnan(tempRead) %如果是NAN就變0
                        tempRead=0;
                    end
                    tempRead2500(readDataCount) = tempRead; %加到後面

                    app.LabelDataLength.Text = "記錄資料長度: "+(tempAdcDataLength+readDataCount)+" -> "+(tempAdcDataLength/2500);
                end
                
                nowTime = string(datetime('now','TimeZone','local','Format','yMMdd_HHmmss.SSS')); %現在時間
                save(app.checkpointFolder+"/checkpoint_"+nowTime+".mat", 'tempRead2500'); %暫存資料

                app.AdcData = [app.AdcData, tempRead2500]; %每2500筆資料一次加進去AdcData不然資料約15萬筆時會當機
                %tempRead2500=[] %清空2500筆資料的暫存;
                if length(app.AdcData)<5000 %如果資料不足5000筆就跳過，通常只有第一次有用
                    continue;
                end
                plot(app.UIAxes, app.AdcData(end-4999:end)); %取最後5000筆);
            end
            result=true;
            app.disconnect();
        end

        function [test_predict_label]=getClass(app, input)
            %assignin('base','input', input);
            load("oil_svmmodel.mat");
            load("PS.mat"); %載入PS，normalizatioin用
        
            fs = 977;
            lowpass_f = 200;
        
            %低通濾波
            lowPassInput=lowpass(input', lowpass_f, fs);
            lowPassInput=lowPassInput(201:4800);
        
            %%先來計算統計特徵和MFCC吧
            
            satistical_features = Extract_Sattistical_Features(lowPassInput,fs);
            
            % Extract Mel-frequency cepstral coefficients
            %Tw = window_length*1000;      % analysis frame duration (ms)
            Tw = 25;                % analysis frame duration (ms)
            Ts = 10;                % analysis frame shift (ms)
            alpha = 0.97;           % preemphasis coefficient
            M = 20;                 % number of filterbank channels
            C = 12;                 % number of cepstral coefficients
            L = 22;                 % cepstral sine lifter parameter
            LF = 5;                 % lower frequency limit (Hz)
            HF = 500;               % upper frequency limit (Hz)
            
            [MFCCs, ~, ~] = mfcc(lowPassInput, fs, Tw, Ts, alpha, @hamming, [LF HF], M, C+1, L);
            feature_train = [satistical_features;MFCCs];
            
            %顯示統計特徵
            app.UITableSatisticalFeatures.Data=satistical_features';

            %normalizatioin
            rand_feature = mapminmax('apply', feature_train, PS);
            rand_feature=rand_feature';
            
            test_predict_label = predict(Md1, rand_feature);
            %return test_predict_label
            %disp(test_predict_label);
        end
        
        %計算每個類別的百分比]
        function [classProbability]=getClassProbability(app)
            classProbability=[0.0, 0.0, 0.0];
            %allLength=app.classCount(1)+app.classCount(2)+app.classCount(3);
            allLength=sum(app.classCount);
            
            classProbability(1)=app.classCount(1)/allLength;
            classProbability(2)=app.classCount(2)/allLength;
            classProbability(3)=app.classCount(3)/allLength;
            classProbability=classProbability*100;
            classProbability=round(classProbability);
        end

        function result = disconnect(app)
            delete(app.serialObject) %關閉COMPROT
            app.Label.Text = "已斷線";
            app.LampConnectStatus.Color=[1.00,0.00,0.00]; %連線燈號亮紅燈
            app.ButtonDisconnect.Enable="off"; %讓斷線按鈕Disable
            app.ButtonConnect.Enable="on"; %讓連線按鈕Enable
            app.ButtonStartRecord.Enable="off";
            result=true;
        end
    end
    
    methods (Access = public)
        
        function results = func2(app)
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            %初始化函式
            %Websocket
%             addpath("websocket");
%             addpath("websocket/src");
%             global server
%             server = EchoServer(30000);

            %取得安裝目錄
%             [~, result] = system('path');
%             appDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
%             app.Label.Text = appDir+app.dataFolder;
%             app.dataFolder=appDir+app.dataFolder;
%             app.checkpointFolder=appDir+app.checkpointFolder;

            %偵測存放資料夾
            if ~exist(app.dataFolder, 'dir') %如果資料夾不存在
               mkdir(app.dataFolder);
            end
            if ~exist(app.checkpointFolder, 'dir') %如果資料夾不存在
               mkdir(app.checkpointFolder);
            end

            %偵測所有可用port
            comportList = serialportlist;
            if isempty(comportList) %如果偵測不到
                comportList=["COM"];
            end
            app.DropDownSelectComport.Items=comportList; %把可用連接埠放到下拉式選單
            app.DropDownSelectComport.Value=comportList(1); %選擇第一個
            focus(app.DropDownSelectComport);
            
            %底下的程式是用來準備測試用的
            addpath('HelperFunctions');
            addpath svm\
            
            %判斷模型檔案是否存在
            modelFileName="oil_svmmodel.mat";
            if ~exist(modelFileName, 'file') %如果檔案不存在
               %app.Label.Text = "模型檔案"+fileName+"不存在";
               app.ButtonStartTesting.Enable="off";
               app.ButtonStopTesting.Enable="off";
               return;
            end
            %Md1 = load(modelFileName); %載入模型
            
            %底下這行程式是讓他先執行一次判讀模型的程式，接下來速度可能會比較快
            app.getClass(1:5000);
            app.UITableSatisticalFeatures.Data=[];
        end

        % Button pushed function: ButtonDisconnect
        function ButtonDisconnectPushed(app, event)
            app.flagConnect=false;
            app.Label.Text = "正在中斷...等待資料滿2500倍數...";
            
        end

        % Button pushed function: ButtonOpenFolder
        function ButtonOpenFolderPushed(app, event)
            app.dataFolder=app.dataFolder;
            if ~exist(app.dataFolder, 'dir') %如果資料夾不存在
               mkdir(app.dataFolder);
            end
            winopen(app.dataFolder);
        end

        % Button pushed function: ButtonConnect
        function ButtonConnectPushed(app, event)
            app.flagConnect=true;
            comport=app.DropDownSelectComport.Value; %取得連接埠
            baudRate=app.DropDownSelectBaudRate.Value; %取得速率
            app.Label.Text = "連線至"+comport+"...";
            app.serialObject = serialport(comport, str2double(baudRate)); %建立連線
            
            app.Label.Text = "連線成功";
            
            app.LampConnectStatus.Color=[0.00,1.00,0.00]; %亮綠燈
            app.ButtonDisconnect.Enable="on"; %讓斷線按鈕Enable
            app.ButtonDisconnect.Tooltip="";
            app.ButtonConnect.Enable="off" %讓連線按鈕Disable
            app.ButtonStartRecord.Enable="on";
            app.ButtonConnect.Tooltip="目前已連線";
            
            app.main();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            if ~isnan(app.serialObject)
                delete(app.serialObject) %關閉COMPROT
            end
            
            clear all;
            close all;
            clc;
%             server.stop;
%             delete(server);
%             clear server;
        end

        % Button pushed function: ButtonStartRecord
        function ButtonStartRecordPushed(app, event)
            app.ButtonStartRecord.Enable="off"; %開始記錄按鈕變不能按
            app.ButtonStopRecord.Enable="on"; %停止紀錄變可以按
            app.ButtonStopRecord.Tooltip="";
            app.ButtonDisconnect.Enable="off";
            app.ButtonDisconnect.Tooltip="需先停止記錄才可以斷線";
            app.EditFieldSetClass.Enable="off";
            app.LampRecordingStatus.Color=[0.00,1.00,0.00]; %紀錄狀態亮綠燈

            app.flagRecording=true;
            app.Label.Text = "準備開始記錄...";

            
            %AdcData
        end

        % Button pushed function: ButtonStopRecord
        function ButtonStopRecordPushed(app, event)
            app.ButtonStartRecord.Enable="on"; %開始記錄按鈕變可以按
            app.ButtonStopRecord.Enable="off"; %停止紀錄變不能按
            app.LampRecordingStatus.Color=[1.00,0.00,0.00]; %紀錄狀態亮紅燈
            app.EditFieldSetClass.Enable="on";
            app.Label.Text = "正在中斷...等待資料滿2500倍數...";

            app.flagRecording=false;
            
        end

        % Button pushed function: ButtonClose
        function ButtonClosePushed(app, event)
            app.delete;
        end

        % Button pushed function: ButtonSaveAdcData
        function ButtonSaveAdcDataPushed(app, event)
            assignin('base','AdcData', app.AdcData);
            tempAdcDataLength=length(app.AdcData);
            app.LabelDataLength.Text = "記錄資料長度: "+tempAdcDataLength+" -> "+(tempAdcDataLength/2500);
            if tempAdcDataLength==0 %如果沒資料
                app.Label.Text = "又沒資料，是要儲存什麼@@?";
                return;
            end
            %save(['test', '.mat'], 'AdcData');
            %app.Label.Text = string(length(app.AdcData));

            saveData=app.AdcData;
            nowTime = string(datetime('now','TimeZone','local','Format','yMMdd_HHmmss.SSS')); %現在時間
            fileName=app.dataFolder+"/"+nowTime+"_"+app.EditFieldSetClass.Value+".mat";
            save(fileName, 'saveData');
            app.Label.Text = "儲存至"+fileName;

            dataFileName = app.dataFolder+"/"+"data"+app.EditFieldSetClass.Value+".mat";
            save(dataFileName, 'saveData');
            app.Label.Text = "儲存至"+dataFileName;
        end

        % Button pushed function: ButtonStartTraining
        function ButtonStartTrainingPushed(app, event)
            fileName=app.dataFolder+"/data1.mat";
            if ~exist(fileName, 'file') %如果檔案不存在
               app.Label.Text = "模型檔案"+fileName+"不存在";
               return;
            end

            fileName=app.dataFolder+"/data3.mat";
            if ~exist(fileName, 'file') %如果檔案不存在
               app.Label.Text = "模型檔案"+fileName+"不存在";
               return;
            end

            fileName=app.dataFolder+"/data4.mat";
            if ~exist(fileName, 'file') %如果檔案不存在
               app.Label.Text = "模型檔案"+fileName+"不存在";
               return;
            end
            app_traininging;
        end

        % Button pushed function: ButtonStartTesting
        function ButtonStartTestingPushed(app, event)
            %main_withoutWebsocket;
            
            
            app.flagTesting=true;

        end

        % Button pushed function: ButtonStopTesting
        function ButtonStopTestingPushed(app, event)
            app.flagTesting=false;
            app.classCount=[0, 0, 0];
            app.Label.Text="停止測試";
            app.UITable.Data=app.classCount;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '即時數據')
            xlabel(app.UIAxes, '時間')
            ylabel(app.UIAxes, '電壓')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 1000 2000 3000 4000 5000];
            app.UIAxes.XTickLabel = {'0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.UIAxes.YTick = [0 1 2 3 4 5];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.Label = uilabel(app.LeftPanel);
            app.Label.Tag = 'Status';
            app.Label.Text = '狀態';
            app.LabelDataLength = uilabel(app.LeftPanel);
            app.LabelDataLength.Text = '記錄資料長度:';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'類別1'; '類別 23'; '類別4'};
            app.UITable.RowName = {};
            app.UITableSatisticalFeatures = uitable(app.LeftPanel);
            app.UITableSatisticalFeatures.ColumnName = {'平均值'; '中位數'; '標準差'; '平均差'; '25%'; '75%'; '四分位距'; '偏度'; '峰度'; '夏農熵'; 'spectral熵'; 'maxfreq'; 'maxval'; 'maxratio'};
            app.UITableSatisticalFeatures.RowName = {};
            app.RightPanel = uipanel(app.UIFigure);
            app.ButtonStartRecord = uibutton(app.RightPanel, 'push');
            app.ButtonStartRecord.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartRecordPushed, true);
            app.ButtonStartRecord.Enable = 'off';
            app.ButtonStartRecord.Text = '開始記錄';
            app.ButtonStopRecord = uibutton(app.RightPanel, 'push');
            app.ButtonStopRecord.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopRecordPushed, true);
            app.ButtonStopRecord.Enable = 'off';
            app.ButtonStopRecord.Tooltip = {'目前尚未開始記錄'};
            app.ButtonStopRecord.Text = '停止紀錄';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '連接埠';
            app.DropDownSelectComport = uidropdown(app.RightPanel);
            app.DropDownSelectComport.Items = {'COM1', 'COM2'};
            app.DropDownSelectComport.Editable = 'on';
            app.DropDownSelectComport.Value = 'COM1';
            app.ButtonConnect = uibutton(app.RightPanel, 'push');
            app.ButtonConnect.ButtonPushedFcn = createCallbackFcn(app, @ButtonConnectPushed, true);
            app.ButtonConnect.Text = '連線';
            app.ButtonDisconnect = uibutton(app.RightPanel, 'push');
            app.ButtonDisconnect.ButtonPushedFcn = createCallbackFcn(app, @ButtonDisconnectPushed, true);
            app.ButtonDisconnect.Enable = 'off';
            app.ButtonDisconnect.Tooltip = {'目前尚未連線'};
            app.ButtonDisconnect.Text = '斷線';
            app.ButtonOpenFolder = uibutton(app.RightPanel, 'push');
            app.ButtonOpenFolder.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenFolderPushed, true);
            app.ButtonOpenFolder.Text = '開啟資料夾';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '記錄狀態';
            app.LampRecordingStatus = uilamp(app.RightPanel);
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Text = '設定類別';
            app.EditFieldSetClass = uieditfield(app.RightPanel, 'text');
            app.EditFieldSetClass.Value = '1';
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Text = '連線狀態';
            app.LampConnectStatus = uilamp(app.RightPanel);
            app.ButtonClose = uibutton(app.RightPanel, 'push');
            app.ButtonClose.ButtonPushedFcn = createCallbackFcn(app, @ButtonClosePushed, true);
            app.ButtonClose.Text = '結束程式';
            app.Label_7 = uilabel(app.RightPanel);
            app.Label_7.Text = '速率';
            app.DropDownSelectBaudRate = uidropdown(app.RightPanel);
            app.DropDownSelectBaudRate.Items = {'300', '1200', '2400', '4800', '9600', '19200', '38400', '57600', '74880', '115200', '230400', '250000', '500000', '1000000', '2000000'};
            app.DropDownSelectBaudRate.Editable = 'on';
            app.DropDownSelectBaudRate.Value = '115200';
            app.ButtonSaveAdcData = uibutton(app.RightPanel, 'push');
            app.ButtonSaveAdcData.ButtonPushedFcn = createCallbackFcn(app, @ButtonSaveAdcDataPushed, true);
            app.ButtonSaveAdcData.Text = '儲存資料';
            app.ButtonStartTraining = uibutton(app.RightPanel, 'push');
            app.ButtonStartTraining.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartTrainingPushed, true);
            app.ButtonStartTraining.Text = '開始訓練';
            app.ButtonStartTesting = uibutton(app.RightPanel, 'push');
            app.ButtonStartTesting.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartTestingPushed, true);
            app.ButtonStartTesting.Text = '開始測試';
            app.ButtonStopTesting = uibutton(app.RightPanel, 'push');
            app.ButtonStopTesting.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopTestingPushed, true);
            app.ButtonStopTesting.Text = '停止測試';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end